classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EntradadeDadosUIFigure       matlab.ui.Figure
        AplicarButton                matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        UITable_2                    matlab.ui.control.Table
        ImportarDadosdaLTLabel       matlab.ui.control.Label
        ImportarDadosdasBarrasLabel  matlab.ui.control.Label
        ErrodesejadoEditFieldLabel   matlab.ui.control.Label
        ErrodesejadoEditField        matlab.ui.control.NumericEditField
        TotaldeperdasnasbarrasPanel  matlab.ui.container.Panel
        AtivaEditFieldLabel          matlab.ui.control.Label
        AtivaEditField               matlab.ui.control.NumericEditField
        ReativaEditFieldLabel        matlab.ui.control.Label
        ReativaEditField             matlab.ui.control.NumericEditField
        ErromximoEditFieldLabel      matlab.ui.control.Label
        ErromximoEditField           matlab.ui.control.NumericEditField
    end

    properties (Access = private)
        dataBars_mod; % Variável para guardar a tabela modificada.
        PowerFlowWindow; % Janela de fluxo de potência
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = xlsread('Dados_LT.xlsx'); % Lê o arquivo e insere na tabela 1
            [app.UITable_2.Data,app.dataBars_mod,row_1,row_2,row_3] = bar_type_add(xlsread('Dados_Barras.xlsx')); % Lê o arquivo, completa a tabela 2, retorna variáveis auxiliares
            set_color; % Script para troca de cores na tabela 2
            msg = sprintf('Valores das planilhas padrões carregados! \nCaso deseje carregar outras planilhas selecione "Importar Dados."');
            uialert(app.EntradadeDadosUIFigure,msg,'Atenção','Icon','warning');
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Lendo o arquivo selecionado e inserindo na tabela 1
            [file,path] = uigetfile('.xlsx');
            filename = [path file];
            app.UITable.Data = xlsread(filename);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [file,path] = uigetfile('.xlsx');
            filename = [path file];
            [data_bars] = xlsread(filename); % Guarda os dados recebidos na tabela de barras.
            [data_bars] = sortrows(data_bars); % Reordena as linhas da tabela de barras em ordem crescente
            [app.UITable_2.Data,app.dataBars_mod,row_1,row_2,row_3] = bar_type_add(data_bars); % Completa a tabela 2, retorna variáveis auxiliares
            removeStyle(app.UITable_2); % Apaga as cores anteriores
            set_color; % Script para troca de cores na tabela 2
            % Zerando as perdas totais
            app.AtivaEditField.Value = 0;
            app.ReativaEditField.Value = 0;
            app.ErromximoEditField.Value = 0;
            app.AplicarButton.Enable = 'on'; % Ativa o botão Aplicar
        end

        % Button pushed function: AplicarButton
        function AplicarButtonPushed(app, event)
            % Desabilita o botao Aplicar
            app.AplicarButton.Enable = 'off';
            % Chamando o arquivo de cálculos e salvando os valores
            [bars_table,bars_loss,power_flux,TL_loss,max_error] = calc(app.UITable.Data,app.dataBars_mod,app.ErrodesejadoEditField.Value);
            app.UITable_2.Data(:,3:6) = bars_table(:,3:6);
            app.AtivaEditField.Value = cell2mat(bars_loss(1,1));
            app.ReativaEditField.Value = cell2mat(bars_loss(1,2));
            app.ErromximoEditField.Value = max_error;
            % Chamando a janela de fluxo de potência
            app.PowerFlowWindow = power_flow_window(app,power_flux,TL_loss);
            save_all; % Script para salvar as tabelas
            msg = sprintf('As tabelas geradas foram salvas.');
            uialert(app.EntradadeDadosUIFigure,msg,'Sucesso!','Icon','info');
        end

        % Close request function: EntradadeDadosUIFigure
        function EntradadeDadosUIFigureCloseRequest(app, event)
            % Deleta as duas janelas
            delete(app.PowerFlowWindow);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EntradadeDadosUIFigure = uifigure('Visible', 'off');
            app.EntradadeDadosUIFigure.Name = 'Entrada de Dados';
            app.EntradadeDadosUIFigure.CloseRequestFcn = createCallbackFcn(app, @EntradadeDadosUIFigureCloseRequest, true);
            app.AplicarButton = uibutton(app.EntradadeDadosUIFigure, 'push');
            app.AplicarButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarButtonPushed, true);
            app.AplicarButton.Text = 'Aplicar';
            app.Button = uibutton(app.EntradadeDadosUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'importdata.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.EntradadeDadosUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'importdata.png';
            app.Button_2.Text = '';
            app.UITable = uitable(app.EntradadeDadosUIFigure);
            app.UITable.ColumnName = {'Barra k'; 'Barra m'; 'Admitância Real'; 'Admitância Imaginária'; 'akm'; 'ϕkm'; 'bkm'};
            app.UITable.RowName = {};
            app.UITable_2 = uitable(app.EntradadeDadosUIFigure);
            app.UITable_2.ColumnName = {'Barra'; 'Classificação da Barra'; 'V'; 'θ (rad)'; 'P'; 'Q'; 'bsh'};
            app.UITable_2.RowName = {};
            app.ImportarDadosdaLTLabel = uilabel(app.EntradadeDadosUIFigure);
            app.ImportarDadosdaLTLabel.Text = 'Importar Dados da LT';
            app.ImportarDadosdasBarrasLabel = uilabel(app.EntradadeDadosUIFigure);
            app.ImportarDadosdasBarrasLabel.Text = 'Importar Dados das Barras';
            app.ErrodesejadoEditFieldLabel = uilabel(app.EntradadeDadosUIFigure);
            app.ErrodesejadoEditFieldLabel.Text = 'Erro desejado';
            app.ErrodesejadoEditField = uieditfield(app.EntradadeDadosUIFigure, 'numeric');
            app.ErrodesejadoEditField.Limits = [0 Inf];
            app.ErrodesejadoEditField.Value = 0.01;
            app.TotaldeperdasnasbarrasPanel = uipanel(app.EntradadeDadosUIFigure);
            app.TotaldeperdasnasbarrasPanel.Title = 'Total de perdas nas barras';
            app.AtivaEditFieldLabel = uilabel(app.TotaldeperdasnasbarrasPanel);
            app.AtivaEditFieldLabel.Text = 'Ativa';
            app.AtivaEditField = uieditfield(app.TotaldeperdasnasbarrasPanel, 'numeric');
            app.AtivaEditField.Editable = 'off';
            app.ReativaEditFieldLabel = uilabel(app.TotaldeperdasnasbarrasPanel);
            app.ReativaEditFieldLabel.Text = 'Reativa';
            app.ReativaEditField = uieditfield(app.TotaldeperdasnasbarrasPanel, 'numeric');
            app.ReativaEditField.Editable = 'off';
            app.ErromximoEditFieldLabel = uilabel(app.EntradadeDadosUIFigure);
            app.ErromximoEditFieldLabel.Text = 'Erro máximo';
            app.ErromximoEditField = uieditfield(app.EntradadeDadosUIFigure, 'numeric');
            app.ErromximoEditField.Editable = 'off';
            % Show the figure after all components are created
            app.EntradadeDadosUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EntradadeDadosUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EntradadeDadosUIFigure)
        end
    end
end